classdef assignment_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        ForceMultiplierSpinner       matlab.ui.control.Spinner
        ForceMultiplierSpinnerLabel  matlab.ui.control.Label
        UpdateButton                 matlab.ui.control.Button
        MembersPlot                  matlab.ui.control.UIAxes
        TabGroup                     matlab.ui.container.TabGroup
        FilesTab                     matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        ChooseanExampleLabel         matlab.ui.control.Label
        ImportDataLabel              matlab.ui.control.Label
        ExamplesDropDown             matlab.ui.control.DropDown
        ExamplesLabel                matlab.ui.control.Label
        NodeBrowseButton             matlab.ui.control.Button
        MemberBrowseButton           matlab.ui.control.Button
        NodeFileEditField            matlab.ui.control.EditField
        NodeFileEditFieldLabel       matlab.ui.control.Label
        MemberFileEditField          matlab.ui.control.EditField
        MemberFileEditFieldLabel     matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        MembersTab                   matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        RemoveMemberButton           matlab.ui.control.Button
        Switch                       matlab.ui.control.Switch
        AddMemberButton              matlab.ui.control.Button
        ElementsTable                matlab.ui.control.Table
        NodesTab                     matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        NodesTable                   matlab.ui.control.Table
        HelpTab                      matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        TabGroup2                    matlab.ui.container.TabGroup
        FilesTab_2                   matlab.ui.container.Tab
        Image3                       matlab.ui.control.Image
        MembersTab_2                 matlab.ui.container.Tab
        Image2                       matlab.ui.control.Image
        NodesTab_2                   matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        element_count = 0;
        saved_elements;
        saved_nodes;
        element_files = strings(0);
        node_files = strings(0);
        filenames = strings(0);
        K_G;
        dimensions; % Description
    end
    
    methods (Access = private)

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %          Helper-functions         %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Disables editing for both tables
        function [] = disable_tables(app)
            app.ElementsTable.ColumnEditable = [false, false, false, false , false];
            if app.dimensions == 2
                app.NodesTable.ColumnEditable = [false, false, false, false , false, false, false];
            else
                app.NodesTable.ColumnEditable = [false, false, false, false , false, false, false, false, false, false];
            end
        end

        % Enables editing and clears all the data in the tables
        function [] = enable_and_reset_tables(app)
            % enable editing
            app.ElementsTable.ColumnEditable = [false, true, true, true , true];
            if app.dimensions == 2
                app.NodesTable.ColumnEditable = [false, true, true, true , true, true , true];
            else
                app.NodesTable.ColumnEditable = [false, true, true, true , true, true , true, true, true, true];
            end

            % hard reset all saved values
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            app.ElementsTable.Data = [];
            app.NodesTable.Data = [];
            app.saved_nodes = {};
            app.saved_elements = {};
            app.element_count = 0;
        end

        % Configure the dimension switches value and visiblity
        function [] = update_buttons(app, value, setVisible)
            if value == "2D"
                app.dimensions = 2;
            elseif value == "3D"
                app.dimensions = 3;
            else
                error('Unknown switch state: %s', value)
            end
            app.Switch.Value = value;
            app.Switch.Visible = setVisible;
            app.AddMemberButton.Visible = setVisible;
            app.RemoveMemberButton.Visible = setVisible;
        end
        
        % updates the app data from the give file paths
        function [] = update_data_from_paths(app, node_file_path, elements_file_path)
            nodes_matrix = readmatrix(node_file_path);
            if size(nodes_matrix, 2) > 7
                update_buttons(app, "3D", false);
            else
                update_buttons(app, "2D", false);
            end
            app.ElementsTable.Data = readmatrix(elements_file_path);
            app.NodesTable.Data = nodes_matrix;
            update_node_data(app);
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %          Pre-Processing           %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Ensure nodes are non-nan before updating node table
        function [has_zero] = node_cols_has_nan(app)
            has_zero = false;
            for i = 1:size(app.ElementsTable.Data, 1)
                if isnan(app.ElementsTable.Data(i, 2)) || isnan(app.ElementsTable.Data(i, 3))
                    has_zero = true;
                end
            end
        end

        % Ensure nodes are non-zero before updating node table
        function [has_nan] = node_cols_has_zero(app)
            has_nan = false;
            for i = 1:size(app.ElementsTable.Data, 1)
                if app.ElementsTable.Data(i, 2) == 0 || app.ElementsTable.Data(i, 3) == 0
                    has_nan = true;
                end
            end
        end

        % Ensure nodes are unique before updating node table
        function [has_identical_pair] = node_pairs_not_unique(app)
            has_identical_pair = false;
            for i = 1:size(app.ElementsTable.Data, 1)
                if app.ElementsTable.Data(i, 2) == app.ElementsTable.Data(i, 3)
                    has_identical_pair = true;
                end
            end
        end

        % validate node column inputs
        function [is_valid] = nodes_validated(app)
            is_valid = true;
            if (size(app.ElementsTable.Data, 1) < 1)
                errordlg("Error: No members defined", "Input Error")
                is_valid = false;
            end

            if (node_cols_has_zero(app))
                errordlg("Error: Node numbers should be non zero", "Input Error")
                is_valid = false;
            end

            if (node_cols_has_nan(app))
                errordlg("Error: Node numbers should not be NaN", "Input Error")
                is_valid = false;
            end

            if (node_pairs_not_unique(app))
                errordlg("Error: Start and end node should not be the same", "Input Error")
                is_valid = false;
            end
        end

        % Initalize data in the nodes table
        function [] = update_node_table(app)
            tableNodes = app.ElementsTable.Data(:, 2:3);
            nodes = unique(tableNodes);
            if app.dimensions == 3
                cols = ["Node","X","Y","Z","X Force","Y Force","Z Force","X Displacement","Y Displacement","Z Displacement"];
                fields = ["index", "x","y","z","x_force","y_force","z_force","x_disp","y_disp","z_disp"];
            else                
                cols = ["Node", "X", "Y", "X Force", "Y Force", "X Displacement", "Y Displacement"];
                fields = ["index", "x", "y", "x_force", "y_force", "x_disp", "y_disp"];
            end
            app.NodesTable.ColumnName = cols;
            app.NodesTable.Data = [];
            for i = 1:size(nodes, 1)
                app.NodesTable.Data(i, :) = NaN(1, size(fields, 2));
                app.saved_nodes(i).index = nodes(i);
                app.NodesTable.Data(i,1) = app.saved_nodes(i).index;
                for j = 2:size(fields, 2)
                    field = fields(1, j);
                    if isfield(app.saved_nodes(i), field)
                        app.NodesTable.Data(i, j) = app.saved_nodes(i).(field);
                    else
                        app.NodesTable.Data(i, j) = NaN;
                    end
                end
            end
        end

        % Save all of the data in the nodes table.
        function [] = update_node_data(app)
            nodes = unique(app.ElementsTable.Data(:, 2:3));
            app.saved_nodes = {};
            if app.dimensions == 2 % 2D space
                for i = 1:size(nodes, 1)
                    app.saved_nodes(i).index = app.NodesTable.Data(i, 1);
                    app.saved_nodes(i).x = app.NodesTable.Data(i, 2);
                    app.saved_nodes(i).y = app.NodesTable.Data(i, 3);
                    app.saved_nodes(i).x_force = app.NodesTable.Data(i, 4);
                    app.saved_nodes(i).y_force = app.NodesTable.Data(i, 5);
                    app.saved_nodes(i).x_disp = app.NodesTable.Data(i, 6);
                    app.saved_nodes(i).y_disp = app.NodesTable.Data(i, 7);
                end
            else % 3D space
                for i = 1:size(nodes, 1)
                    app.saved_nodes(i).index = app.NodesTable.Data(i, 1);
                    app.saved_nodes(i).x = app.NodesTable.Data(i, 2);
                    app.saved_nodes(i).y = app.NodesTable.Data(i, 3);
                    app.saved_nodes(i).z = app.NodesTable.Data(i, 4);
                    app.saved_nodes(i).x_force = app.NodesTable.Data(i, 5);
                    app.saved_nodes(i).y_force = app.NodesTable.Data(i, 6);
                    app.saved_nodes(i).z_force = app.NodesTable.Data(i, 7);
                    app.saved_nodes(i).x_disp = app.NodesTable.Data(i, 8);
                    app.saved_nodes(i).y_disp = app.NodesTable.Data(i, 9);
                    app.saved_nodes(i).z_disp = app.NodesTable.Data(i, 10);
                end
            end
        end

        % Save all of the data in the elements table.
        function [] = update_element_data(app)
            app.saved_elements = {};
            for i = 1:size(app.ElementsTable.Data, 1)
                app.saved_elements(i).index = app.ElementsTable.Data(i, 1);
                app.saved_elements(i).start_node = app.ElementsTable.Data(i, 2);
                app.saved_elements(i).end_node = app.ElementsTable.Data(i, 3);
                app.saved_elements(i).elasticity = app.ElementsTable.Data(i, 4);
                app.saved_elements(i).area = app.ElementsTable.Data(i, 5);
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %           Calculations            %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % the main calculation function
        function [] = calculate(app)
            % generate matrices
            update_node_data(app);
            update_element_data(app);
            calculate_alpha_and_length_values(app);
            calculate_AEoverL_constants(app);
            calculate_general_stiffness_matrix(app);

            % boundary conditions
            F = get_force_boundary_conditions(app);
            U = get_displacement_boundary_conditions(app);
            remove_unused_rows_and_cols(app, U); % Use BC to clean up K_General

            % solve and save
            U = app.K_G\F;
            update_displacement_values(app, U);
            solve_internal_forces_and_stresses(app);
        end

        % calculates all the alpha values from an array of node pairs
        function [] = calculate_alpha_and_length_values(app)
            arrLength = size(app.saved_elements, 2);
            for i = 1:arrLength
                % for readiblity
                start_node = app.saved_nodes(app.saved_elements(i).start_node);
                end_node = app.saved_nodes(app.saved_elements(i).end_node);

                % calculate
                deltaY = (end_node.y - start_node.y);
                deltaX = (end_node.x - start_node.x);
                if app.dimensions == 3 % use direction cosines
                    deltaZ = (end_node.z - start_node.z);
                    length = norm([deltaX deltaY deltaZ]);
                    app.saved_elements(i).length = length;
                    app.saved_elements(i).cx = deltaX/length;
                    app.saved_elements(i).cy = deltaY/length;
                    app.saved_elements(i).cz = deltaZ/length;
                else % use angles
                    app.saved_elements(i).alpha = atan2(deltaY, deltaX);
                    app.saved_elements(i).length = norm([deltaX deltaY]);
                end
            end
        end

        % calculates AE/L for each member
        function [] = calculate_AEoverL_constants(app)
            for i = 1:size(app.saved_elements, 2)
                if app.saved_elements(i).length ~= 0 && ~isnan(app.saved_elements(i).length)
                    A = app.saved_elements(i).area;
                    E = app.saved_elements(i).elasticity;
                    L = app.saved_elements(i).length;
                    app.saved_elements(i).AEoverL = A*E/L;
                else
                    errordlg("Divide by zero error: Member " + i + " has a length of zero", "Input Error")
                end
            end
        end

        % subroutine that creates a standard 4x4 stiffness matrix using the given alpha
        function [K] = generate_stiffness_matrix(app, i)
            AEoverL = app.saved_elements(i).AEoverL;
            if app.dimensions == 3 % use direction cosines
                cx = app.saved_elements(i).cx;
                cy = app.saved_elements(i).cy;
                cz = app.saved_elements(i).cz;
                K = AEoverL * [
                    cx^2, cx*cy, cx*cz, -cx^2, -cx*cy, -cx*cz;
                    cx*cy, cy^2, cy*cz, -cx*cy, -cy^2, -cy*cz;
                    cx*cz, cy*cz, cz^2, -cx*cz, -cy*cz, -cz^2;
                    -cx^2, -cx*cy, -cx*cz, cx^2, cx*cy, cx*cz;
                    -cx*cy, -cy^2, -cy*cz, cx*cy, cy^2, cy*cz;
                    -cx*cz, -cy*cz, -cz^2, cx*cz, cy*cz, cz^2;
                ];
            else % use angle
                alpha = app.saved_elements(i).alpha;
                c = cos(alpha);
                s = sin(alpha);
                K = AEoverL * [
                    c^2,  c*s, -c^2, -c*s;
                    c*s,  s^2, -c*s, -s^2;
                    -c^2, -c*s,  c^2,  c*s;
                    -c*s, -s^2,  c*s,  s^2;
                ];
            end
        end

        % subroutine that converts a standard 4x4 stiffness matrix to the general size,
        % which is 2x the number of nodes
        function [K] = convert_to_general_matrix(app, gen_matrix_size, K_i, n, m)
            K = zeros(gen_matrix_size);
            dim = app.dimensions;
            offset = dim - 1;
            K(dim*n-offset:dim*n, dim*n-offset:dim*n) = K_i(1:dim, 1:dim);
            K(dim*n-offset:dim*n, dim*m-offset:dim*m) = K_i(1:dim, dim+1:end);
            K(dim*m-offset:dim*m, dim*n-offset:dim*n) = K_i(dim+1:end, 1:dim);
            K(dim*m-offset:dim*m, dim*m-offset:dim*m) = K_i(dim+1:end, dim+1:end);
        end

        % calculates the general stiffness matrices using the table data
        function [] = calculate_general_stiffness_matrix(app)
            n_nodes = size(app.saved_nodes, 2);
            gen_matrix_size = app.dimensions*n_nodes;
            app.K_G = zeros(gen_matrix_size);
            for i = 1:size(app.ElementsTable.Data, 1)
                app.K_G = app.K_G + convert_to_general_matrix( ...
                        app, ...
                        gen_matrix_size, ...
                        generate_stiffness_matrix(app, i), ...
                        app.saved_elements(i).start_node, ...
                        app.saved_elements(i).end_node ...
                    );
            end
        end

        % get the displacement vector using the table data
        function [U] = get_displacement_boundary_conditions(app)
            n_nodes = size(app.saved_nodes, 2);
            dim = app.dimensions;
            x_offset = dim - 1;
            y_offset = dim - 2;
            U = NaN(dim*n_nodes, 1);
            for n = 1:n_nodes
                U(dim*n - x_offset, 1) = app.saved_nodes(n).x_disp;
                U(dim*n - y_offset, 1) = app.saved_nodes(n).y_disp;
                if dim == 3
                    U(dim*n, 1) = app.saved_nodes(n).z_disp;
                end
            end
        end

        % save the solutions in node structs
        function [] = update_displacement_values(app, U)
            dim = app.dimensions;
            x_offset = dim - 1;
            y_offset = dim - 2;
            for n = 1:size(app.saved_nodes, 2)
                app.saved_nodes(n).x_disp = U(dim*n - x_offset, 1);
                app.saved_nodes(n).y_disp = U(dim*n - y_offset, 1);
                if dim == 3
                    app.saved_nodes(n).z_disp = U(dim*n, 1);
                end
            end
            update_node_table(app);
        end

        % get the force vector using the table data
        function [F] = get_force_boundary_conditions(app)
            % controlable force multiplier for visual exaggeration
            multiplier = app.ForceMultiplierSpinner.Value;
            n_rows = size(app.saved_nodes, 2);
            dim = app.dimensions;
            x_offset = dim - 1;
            y_offset = dim - 2;
            F = NaN(n_rows, 1);
            for n = 1:n_rows
                F(dim*n - x_offset, 1) = app.saved_nodes(n).x_force * multiplier;
                F(dim*n - y_offset, 1) = app.saved_nodes(n).y_force * multiplier;
                if dim == 3
                    F(dim*n, 1) = app.saved_nodes(n).z_force * multiplier;
                end
            end
        end
        
        % removes 0 displacement solutions
        function [] = remove_unused_rows_and_cols(app, U)
            for i = 1:size(U, 1)
                if U(i, 1) == 0
                    app.K_G(i, :) = 0;
                    app.K_G(:, i) = 0;
                    app.K_G(i, i) = 1;
                end
            end
        end

        % finds the internal forces and stresses using the table data
        function [] = solve_internal_forces_and_stresses(app)
            dim = app.dimensions;
            for i = 1:size(app.saved_elements, 2)
                AEoverL = app.saved_elements(i).AEoverL;
                area = app.saved_elements(i).area;
                start_node = app.saved_nodes(app.saved_elements(i).start_node);
                end_node = app.saved_nodes(app.saved_elements(i).end_node);
                if dim == 3 % 3D: use direction cosines
                    cx = app.saved_elements(i).cx;
                    cy = app.saved_elements(i).cy;
                    cz = app.saved_elements(i).cz;
                    intern_force = AEoverL*( ...
                        cx*(start_node.x_disp - end_node.x_disp) + ...
                        cy*(start_node.y_disp - end_node.y_disp) + ...
                        cz*(start_node.z_disp - end_node.z_disp) ...
                    );
                else % 2D: Use alpha
                    alpha = app.saved_elements(i).alpha;
                    intern_force = AEoverL*( ...
                        (start_node.x_disp - end_node.x_disp)*cos(alpha) + ...
                        (start_node.y_disp - end_node.y_disp)*sin(alpha) ...
                    );
                end
                app.ElementsTable.Data(i, 6) = intern_force;
                app.ElementsTable.Data(i, 7) = intern_force / area;
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %          Post-Processing          %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % reset the plot
        function [] = clear_plot(app)
            cla(app.MembersPlot, 'reset');
        end

        % plots the defined members in 2d or 3d
        function [] = plot_members(app)
            hold(app.MembersPlot, "on");
            for i = 1:size(app.saved_elements, 2)
                start_node = app.saved_nodes(app.saved_elements(i).start_node);
                end_node = app.saved_nodes(app.saved_elements(i).end_node);    
                switch(app.dimensions)
                    case 2 %2D
                        view(app.MembersPlot, [0 90])
                        % plot the line
                        plot(app.MembersPlot, [start_node.x end_node.x], [start_node.y end_node.y], "-b");
                        % plot the node numbers
                        text(app.MembersPlot, start_node.x, start_node.y, num2str(start_node.index));
                        text(app.MembersPlot, end_node.x, end_node.y, num2str(end_node.index));
                    case 3 % 3D
                        view(app.MembersPlot, [5 2 5])
                        % plot the line
                        plot3(app.MembersPlot, [start_node.x end_node.x],[start_node.y end_node.y],[start_node.z end_node.z], "-b")
                        % plot the node numbers
                        text(app.MembersPlot, start_node.x, start_node.y, start_node.z, num2str(start_node.index));
                        text(app.MembersPlot, end_node.x, end_node.y, end_node.z, num2str(end_node.index));
                end
            end
            hold(app.MembersPlot, "off");
        end

        % plot the members with displacement due to the external forces
        function [] = plot_displaced_members(app)
            hold(app.MembersPlot, "on");
            for i = 1:size(app.saved_elements, 2)
                start_node = app.saved_nodes(app.saved_elements(i).start_node);
                end_node = app.saved_nodes(app.saved_elements(i).end_node);
                switch(app.dimensions)
                    case 2
                        % plot the line
                        plot(app.MembersPlot, ...
                            [(start_node.x + start_node.x_disp) (end_node.x + end_node.x_disp)], ...
                            [(start_node.y + start_node.y_disp) (end_node.y + end_node.y_disp)], ...
                            "-r");
                        
                        % plot the node numbers
                        text(app.MembersPlot, ...
                            (start_node.x + start_node.x_disp), ...
                            (start_node.y + start_node.y_disp), ...
                            num2str(start_node.index));
                        text(app.MembersPlot, ...
                            (end_node.x + end_node.x_disp), ...
                            (end_node.y + end_node.y_disp), ...
                            num2str(end_node.index));
                    case 3
                        % plot the line
                        plot3(app.MembersPlot, ...
                            [(start_node.x + start_node.x_disp) (end_node.x + end_node.x_disp)], ...
                            [(start_node.y + start_node.y_disp) (end_node.y + end_node.y_disp)], ...
                            [(start_node.z + start_node.z_disp) (end_node.z + end_node.z_disp)], ...
                            "-r");
                        % plot the node numbers
                        text(app.MembersPlot, ...
                            (start_node.x + start_node.x_disp), ...
                            (start_node.y + start_node.y_disp), ...
                            (start_node.z + start_node.z_disp), ...
                            num2str(start_node.index));
                        text(app.MembersPlot, ...
                            (end_node.x + end_node.x_disp), ...
                            (end_node.y + end_node.y_disp), ...
                            (end_node.z + end_node.z_disp), ...
                            num2str(end_node.index));
                end
            end
            hold(app.MembersPlot, "off");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ElementsTable.ColumnFormat = {'shortG'};
            app.NodesTable.ColumnFormat = {'shortG'};
            update_buttons(app, "2D", true);
        end

        % Button pushed function: AddMemberButton
        function AddMemberButtonPushed(app, event)
            app.ElementsTable.Data(end+1, :) = NaN(1,7); % Add Row of NaNs
            app.element_count = app.element_count + 1;
            app.ElementsTable.Data(app.element_count, 1) = app.element_count;
        end

        % Button pushed function: RemoveMemberButton
        function RemoveMemberButtonPushed(app, event)
            if (size(app.ElementsTable.Data) > 0)
                app.ElementsTable.Data(end,:) = []; % Delete Row
                app.element_count = app.element_count - 1;
            end
        end

        % Cell edit callback: NodesTable
        function NodesTableCellEdit(app, event)
            update_node_data(app);
        end

        % Cell edit callback: ElementsTable
        function ElementsTableCellEdit(app, event)
            update_element_data(app);
        end

        % Button pushed function: UpdateButton
        function UpdateGraphButtonPushed(app, event)
            if ~(nodes_validated(app))
                return
            end
            calculate(app);
            clear_plot(app);
            plot_members(app);
            plot_displaced_members(app);
            axis(app.MembersPlot,"auto");
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            question = app.ExamplesDropDown.Value;
            if app.TabGroup.SelectedTab.Title == "Nodes"
                validated = nodes_validated(app);
                if ~validated
                    app.TabGroup.SelectedTab = event.OldValue;
                elseif (question == "Custom")
                    update_node_table(app);
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "2D"
                app.dimensions = 2;
            elseif value == "3D"
                app.dimensions = 3;
            elsedata
                error('Unknown switch state: %s', value)
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            node_file_path = app.NodeFileEditField.Value;
            elements_file_path = app.MemberFileEditField.Value;
            if isfile(node_file_path) && isfile(elements_file_path)
                update_data_from_paths(app, node_file_path, elements_file_path);
            else
                error('One of the inputs is not a valid file:\n\tMembers file: %s\n\tNodes file: %s', ...
                    node_file_path, elements_file_path);
            end
        end

        % Button pushed function: MemberBrowseButton
        function MemberBrowseButtonPushed(app, event)
            [file, path] = uigetfile('*.xls', 'Choose Member File');
            if file ~= 0
                app.MemberFileEditField.Value = join([path, file]);
            end
        end

        % Button pushed function: NodeBrowseButton
        function NodeBrowseButtonPushed(app, event)
            [file, path] = uigetfile('*.xls', 'Choose Node File');
            if file ~= 0
                app.NodeFileEditField.Value = join([path, file]);
            end
        end

        % Value changed function: ExamplesDropDown
        function ExamplesDropDownValueChanged(app, event)
            value = app.ExamplesDropDown.Value;

            switch(value)
                case "None"
                    enable_and_reset_tables(app);
                    update_buttons(app, "2D", true);
                    clear_plot(app);
                case "Example 1"
                    update_buttons(app, "2D", false);
                    app.ElementsTable.Data = readmatrix("data/example1_elements.xls");
                    app.NodesTable.Data = readmatrix("data/example1_nodes.xls");
                    update_element_data(app);
                    update_node_data(app);
                case "Figure 1"
                    update_buttons(app, "2D", false);
                    disable_tables(app);
                    app.ElementsTable.Data = readmatrix("data/figure1_elements.xls");
                    app.NodesTable.Data = readmatrix("data/figure1_nodes.xls");
                    update_element_data(app);
                    update_node_data(app);
                case "Figure 2"
                    update_buttons(app, "2D", false);
                    disable_tables(app);
                    app.ElementsTable.Data = readmatrix("data/figure2_elements.xls");
                    app.NodesTable.Data = readmatrix("data/figure2_nodes.xls");
                    update_element_data(app);
                    update_node_data(app);
                case "Figure 3"
                    update_buttons(app, "3D", false);
                    app.ElementsTable.Data = readmatrix("data/figure3_elements.xls");
                    app.NodesTable.Data = readmatrix("data/figure3_nodes.xls");
                    update_element_data(app);
                    update_node_data(app);
                case "Figure 4"
                    update_buttons(app, "3D", false);
                    app.ElementsTable.Data = readmatrix("data/figure4_elements.xls");
                    app.NodesTable.Data = readmatrix("data/figure4_nodes.xls");
                    update_element_data(app);
                    update_node_data(app);
                otherwise
                    error('Unknown data: %s.', value)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.GridLayout8 = uigridlayout(app.FilesTab);
            app.LoadButton = uibutton(app.GridLayout8, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.MemberFileEditFieldLabel = uilabel(app.GridLayout8);
            app.MemberFileEditFieldLabel.Text = 'Member File:';
            app.MemberFileEditField = uieditfield(app.GridLayout8, 'text');
            app.NodeFileEditFieldLabel = uilabel(app.GridLayout8);
            app.NodeFileEditFieldLabel.Text = 'Node File:';
            app.NodeFileEditField = uieditfield(app.GridLayout8, 'text');
            app.MemberBrowseButton = uibutton(app.GridLayout8, 'push');
            app.MemberBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @MemberBrowseButtonPushed, true);
            app.MemberBrowseButton.Text = 'Browse...';
            app.NodeBrowseButton = uibutton(app.GridLayout8, 'push');
            app.NodeBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @NodeBrowseButtonPushed, true);
            app.NodeBrowseButton.Text = 'Browse...';
            app.ExamplesLabel = uilabel(app.GridLayout8);
            app.ExamplesLabel.Text = 'Examples:';
            app.ExamplesDropDown = uidropdown(app.GridLayout8);
            app.ExamplesDropDown.Items = {'None', 'Example 1', 'Figure 1', 'Figure 2', 'Figure 3', 'Figure 4'};
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDownValueChanged, true);
            app.ExamplesDropDown.Value = 'None';
            app.ImportDataLabel = uilabel(app.GridLayout8);
            app.ImportDataLabel.Text = 'Import Data';
            app.ChooseanExampleLabel = uilabel(app.GridLayout8);
            app.ChooseanExampleLabel.Text = 'Choose an Example';
            app.MembersTab = uitab(app.TabGroup);
            app.MembersTab.Title = 'Members';
            app.GridLayout4 = uigridlayout(app.MembersTab);
            app.ElementsTable = uitable(app.GridLayout4);
            app.ElementsTable.ColumnName = {'Member'; 'Start Node'; 'End Node'; 'Elasticity (N/m²)'; 'Area (m²)'; 'Internal Force (N)'; 'Stress (N/m²)'};
            app.ElementsTable.RowName = {};
            app.ElementsTable.ColumnEditable = [false true true true true];
            app.ElementsTable.CellEditCallback = createCallbackFcn(app, @ElementsTableCellEdit, true);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.AddMemberButton = uibutton(app.GridLayout5, 'push');
            app.AddMemberButton.ButtonPushedFcn = createCallbackFcn(app, @AddMemberButtonPushed, true);
            app.AddMemberButton.Text = 'Add Member';
            app.Switch = uiswitch(app.GridLayout5, 'slider');
            app.Switch.Items = {'2D', '3D'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '2D';
            app.RemoveMemberButton = uibutton(app.GridLayout5, 'push');
            app.RemoveMemberButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMemberButtonPushed, true);
            app.RemoveMemberButton.Text = 'Remove Member';
            app.NodesTab = uitab(app.TabGroup);
            app.NodesTab.Title = 'Nodes';
            app.GridLayout3 = uigridlayout(app.NodesTab);
            app.NodesTable = uitable(app.GridLayout3);
            app.NodesTable.ColumnName = {'Node'; 'X'; 'Y'; 'X Force'; 'Y Force'; 'X Displacement'; 'Y Displacement'};
            app.NodesTable.RowName = {};
            app.NodesTable.ColumnEditable = [false true true true true true true];
            app.NodesTable.CellEditCallback = createCallbackFcn(app, @NodesTableCellEdit, true);
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.GridLayout9 = uigridlayout(app.HelpTab);
            app.TabGroup2 = uitabgroup(app.GridLayout9);
            app.FilesTab_2 = uitab(app.TabGroup2);
            app.FilesTab_2.Title = 'Files';
            app.Image3 = uiimage(app.FilesTab_2);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'file_help.png');
            app.MembersTab_2 = uitab(app.TabGroup2);
            app.MembersTab_2.Title = 'Members';
            app.Image2 = uiimage(app.MembersTab_2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'member_help.png');
            app.NodesTab_2 = uitab(app.TabGroup2);
            app.NodesTab_2.Title = 'Nodes';
            app.Image = uiimage(app.NodesTab_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'node_help.png');
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.MembersPlot = uiaxes(app.GridLayout6);
            xlabel(app.MembersPlot, 'X (m)')
            ylabel(app.MembersPlot, 'Y (m)')
            zlabel(app.MembersPlot, 'Z')
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.UpdateButton = uibutton(app.GridLayout7, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGraphButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ForceMultiplierSpinnerLabel = uilabel(app.GridLayout7);
            app.ForceMultiplierSpinnerLabel.Text = 'Force Multiplier';
            app.ForceMultiplierSpinner = uispinner(app.GridLayout7);
            app.ForceMultiplierSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end